classdef Group23 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        result                     matlab.ui.control.Label
        DetectedresultLabel        matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        GloveDefectDetectionLabel  matlab.ui.control.Label
        UploadImageButton          matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function results = detectGloveDefect(app,img)

            results = 'cannot detect';
            % change to hsv
            hsvimg = rgb2hsv(img);
            
            % calculate blue percentage
            blue_hLower = 90/179; 
            blue_hUpper = 120/179; 
            blue_sLower = 50/179; 
            blue_sUpper = 255/179; 
            blue_vLower = 50/179; 
            blue_vUpper = 255/179; 
            % Number of pixels of required colour
            requiredColor  = ( hsvimg(:,:,1) >= blue_hLower & hsvimg(:,:,1) <= blue_hUpper ) & ( hsvimg(:,:,2) >= blue_sLower & hsvimg(:,:,2) <= blue_sUpper ) & ( hsvimg(:,:,3) >= blue_vLower & hsvimg(:,:,3) <= blue_vUpper ); 
            % Percentage of colour in the image
            bluePercentColor =  100*(sum(sum(requiredColor))/(size(hsvimg,1)*size(hsvimg,2))); 
            disp('Blue percent: ');
            disp(bluePercentColor);
            
            % calculate yellow percentage
            yellow_hLower = 15/179; 
            yellow_hUpper = 40/179; 
            yellow_sLower = 50/179; 
            yellow_sUpper = 255/179; 
            yellow_vLower = 50/179; 
            yellow_vUpper = 255/179; 
            % Number of pixels of required colour
            requiredColor  = ( hsvimg(:,:,1) >= yellow_hLower & hsvimg(:,:,1) <= yellow_hUpper ) & ( hsvimg(:,:,2) >= yellow_sLower & hsvimg(:,:,2) <= yellow_sUpper ) & ( hsvimg(:,:,3) >= yellow_vLower & hsvimg(:,:,3) <= yellow_vUpper ); 
            % Percentage of colour in the image
            yellowPercentColor =  100*(sum(sum(requiredColor))/(size(hsvimg,1)*size(hsvimg,2))); 
            disp('Yellow percent: ');
            disp(yellowPercentColor);
            
            glove_type = 'none';
            
            %identify glove type
            if (bluePercentColor>=10)
                glove_type = 'plastic';
            elseif (yellowPercentColor>=10)
                glove_type = 'latex';
            else
                glove_type = 'cotton';
            end

            % defect detection by glove type
            if (strcmp(glove_type, 'plastic'))
                % Define a color range to isolate the fingers
                hue_min = 90/179; 
                hue_max = 120/179; 
                saturation_min = 50/179; 
                saturation_max = 255/179; 
                value_min = 50/179; 
                value_max = 255/179; 

                % Apply a mask based on the color range
                mask = (hsvimg(:,:,1) >= hue_min) & (hsvimg(:,:,1) <= hue_max) & ...
                       (hsvimg(:,:,2) >= saturation_min) & (hsvimg(:,:,2) <= saturation_max) & ...
                       (hsvimg(:,:,3) >= value_min) & (hsvimg(:,:,3) <= value_max);

                % Use morphological operations to clean the image
                remove_object_image = bwareaopen(mask, 500); % Remove small objects

                clean_image = imclose(remove_object_image, strel('disk', 5)); % Remove noise and smooth the edges

                holes_image = imfill(clean_image, 'holes'); % Fill holes

                gethole_image = holes_image - clean_image;

                % Define a color range to isolate human skin
                hue_min_skin = 0.05; % Adjust as needed
                hue_max_skin = 0.2; % Adjust as needed
                saturation_min_skin = 0.3; % Adjust as needed
                saturation_max_skin = 0.5; % Adjust as needed
                value_min_skin = 0.4; % Adjust as needed
                value_max_skin = 1; % Adjust as needed
                
                % Apply a mask based on the skin color range
                skin_mask = (hsvimg(:,:,1) >= hue_min_skin) & (hsvimg(:,:,1) <= hue_max_skin) & ...
                            (hsvimg(:,:,2) >= saturation_min_skin) & (hsvimg(:,:,2) <= saturation_max_skin) & ...
                            (hsvimg(:,:,3) >= value_min_skin) & (hsvimg(:,:,3) <= value_max_skin);

                % Check if the gethole_image contains human skin
                gethole_skin = gethole_image & skin_mask;

                % If the gethole_skin image contains any non-zero pixels, it means the hole or tear exposes human skin
                if ~(all(gethole_skin(:)==0))
                    % Label connected components
                    [labeledImg, numComponents] = bwlabel(gethole_image);
                    %disp(numComponents);
                
                    % Analyze the connected components to find tearing or holes
                    stats = regionprops(labeledImg, 'Area', 'BoundingBox', 'Centroid');
                    %disp(stats(1));
                
                    % Set thresholds to differentiate tearing or holes from small artifacts
                    holeThreshold = 1000;
                    tearThreshold = 10000;
                
                    for i = 1:numComponents
                        bbox = stats(i).BoundingBox;
                        if stats(i).Area > holeThreshold && stats(i).Area < tearThreshold
                            % Draw a blue bounding box around the hole region
                            img = insertShape(img, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'blue');
                            % Label the region as a hole
                            img = insertText(img, bbox(1:2), 'Hole', 'FontSize', 50, 'BoxColor', 'blue', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Plastic, Defect: Hole';
                        elseif stats(i).Area >= tearThreshold
                            % Draw a red bounding box around the tearing region
                            img = insertShape(img, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'red');
                            % Label the region as tearing
                            img = insertText(img, bbox(1:2), 'Tear', 'FontSize', 50, 'BoxColor', 'red', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: plastic, Defect: Tear';
                        end
                    end
                
                    % Display the original image with the detected tearing or holes
                    figure;
                    imshow(img);
                    title('Detected Tearing or Holes Image');
                else
                    % Handle dirt or dots detection
                    % Use the original mask to detect the dirt or dots
                    dirt_dots_image = img;
                
                    % Label connected components
                    [labeledDirtDotsImg, numDirtDotsComponents] = bwlabel(gethole_image);
                
                    % Analyze the connected components to find dots
                    dirtDotsStats = regionprops(labeledDirtDotsImg, 'Area', 'BoundingBox');
                
                    % Set thresholds to differentiate tearing or holes from small artifacts
                    dotThreshold = 300;
                    dirtThreshold = 10000;
                
                    for i = 1:numDirtDotsComponents
                    bbox = dirtDotsStats(i).BoundingBox;
                
                        if dirtDotsStats(i).Area > dotThreshold && dirtDotsStats(i).Area < dirtThreshold
                            % Draw a blue bounding box around the hole region
                            dirt_dots_image = insertShape(dirt_dots_image, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'blue');
                            % Label the region as a hole
                            dirt_dots_image = insertText(dirt_dots_image, bbox(1:2), 'Dot', 'FontSize', 50, 'BoxColor', 'blue', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Plastic, Defect: Dots';
                        elseif dirtDotsStats(i).Area >= dirtThreshold
                            % Draw a red bounding box around the tearing region
                            dirt_dots_image = insertShape(dirt_dots_image, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'red');
                            % Label the region as tearing
                            dirt_dots_image = insertText(dirt_dots_image, bbox(1:2), 'Dirty', 'FontSize', 50, 'BoxColor', 'red', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Plastic, Defect: Dirty';
                        end
                    end
                
                
                    % Display the dirt or dots on the glove
                    figure;
                    imshow(dirt_dots_image);
                    title('Detected Dirt or Dots Image');
                end

            elseif (strcmp(glove_type, 'latex'))

                % Define a color range to isolate the fingers
                hue_min = 0.1; 
                hue_max = 0.3;
                saturation_min = 0.5; 
                saturation_max = 1; 
                value_min = 0.4; 
                value_max = 1; 

                % Apply a mask based on the color range
                mask = (hsvimg(:,:,1) >= hue_min) & (hsvimg(:,:,1) <= hue_max) & ...
                       (hsvimg(:,:,2) >= saturation_min) & (hsvimg(:,:,2) <= saturation_max) & ...
                       (hsvimg(:,:,3) >= value_min) & (hsvimg(:,:,3) <= value_max);

                % Use morphological operations to clean the image
                remove_object_image = bwareaopen(mask, 500); % Remove small objects

                clean_image = imclose(remove_object_image, strel('disk', 5)); % Remove noise and smooth the edges

                holes_image = imfill(clean_image, 'holes'); % Fill holes

                gethole_image = holes_image - clean_image;

                % Define a color range to isolate human skin
                hue_min_skin = 0.05; % Adjust as needed
                hue_max_skin = 0.2; % Adjust as needed
                saturation_min_skin = 0.3; % Adjust as needed
                saturation_max_skin = 0.5; % Adjust as needed
                value_min_skin = 0.4; % Adjust as needed
                value_max_skin = 1; % Adjust as needed
                
                % Apply a mask based on the skin color range
                skin_mask = (hsvimg(:,:,1) >= hue_min_skin) & (hsvimg(:,:,1) <= hue_max_skin) & ...
                            (hsvimg(:,:,2) >= saturation_min_skin) & (hsvimg(:,:,2) <= saturation_max_skin) & ...
                            (hsvimg(:,:,3) >= value_min_skin) & (hsvimg(:,:,3) <= value_max_skin);

                % Check if the gethole_image contains human skin
                gethole_skin = gethole_image & skin_mask;

                % If the gethole_skin image contains any non-zero pixels, it means the hole or tear exposes human skin
                if ~(all(gethole_skin(:)==0))
                    % Label connected components
                    [labeledImg, numComponents] = bwlabel(gethole_image);
                    %disp(numComponents);
                
                    % Analyze the connected components to find tearing or holes
                    stats = regionprops(labeledImg, 'Area', 'BoundingBox', 'Centroid');
                    %disp(stats(1));
                
                    % Set thresholds to differentiate tearing or holes from small artifacts
                    holeThreshold = 1000;
                    tearThreshold = 10000;
                
                    for i = 1:numComponents
                        bbox = stats(i).BoundingBox;
                        if stats(i).Area > holeThreshold && stats(i).Area < tearThreshold
                            % Draw a blue bounding box around the hole region
                            img = insertShape(img, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'blue');
                            % Label the region as a hole
                            img = insertText(img, bbox(1:2), 'Hole', 'FontSize', 50, 'BoxColor', 'blue', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Latex, Defect: Hole';
                        elseif stats(i).Area >= tearThreshold
                            % Draw a red bounding box around the tearing region
                            img = insertShape(img, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'red');
                            % Label the region as tearing
                            img = insertText(img, bbox(1:2), 'Tear', 'FontSize', 50, 'BoxColor', 'red', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Latex, Defect: Tear';
                        end
                    end
                
                    % Display the original image with the detected tearing or holes
                    figure;
                    imshow(img);
                    title('Detected Tearing or Holes Image');
                else
                    % Handle dirt or dots detection
                    % Use the original mask to detect the dirt or dots
                    dirt_dots_image = img;
                
                    % Label connected components
                    [labeledDirtDotsImg, numDirtDotsComponents] = bwlabel(gethole_image);
                
                    % Analyze the connected components to find dots
                    dirtDotsStats = regionprops(labeledDirtDotsImg, 'Area', 'BoundingBox');
                
                    % Set thresholds to differentiate tearing or holes from small artifacts
                    dotThreshold = 300;
                    dirtThreshold = 10000;
                
                    for i = 1:numDirtDotsComponents
                    bbox = dirtDotsStats(i).BoundingBox;
                
                        if dirtDotsStats(i).Area > dotThreshold && dirtDotsStats(i).Area < dirtThreshold
                            % Draw a blue bounding box around the hole region
                            dirt_dots_image = insertShape(dirt_dots_image, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'blue');
                            % Label the region as a hole
                            dirt_dots_image = insertText(dirt_dots_image, bbox(1:2), 'Dot', 'FontSize', 50, 'BoxColor', 'blue', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Latex, Defect: Dots';
                        elseif dirtDotsStats(i).Area >= dirtThreshold
                            % Draw a red bounding box around the tearing region
                            dirt_dots_image = insertShape(dirt_dots_image, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'red');
                            % Label the region as tearing
                            dirt_dots_image = insertText(dirt_dots_image, bbox(1:2), 'Dirty', 'FontSize', 50, 'BoxColor', 'red', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Latex, Defect: Dirty';
                        end
                    end
                
                
                    % Display the dirt or dots on the glove
                    figure;
                    imshow(dirt_dots_image);
                    title('Detected Dirt or Dots Image');
                end

            else
                % Define a color range to isolate the fingers
                hue_min = 0; 
                hue_max = 0; 
                saturation_min = 0; 
                saturation_max = 0.1; 
                value_min = 0.6; 
                value_max = 1; 

                % Apply a mask based on the color range
                mask = (hsvimg(:,:,1) >= hue_min) & (hsvimg(:,:,1) <= hue_max) & ...
                       (hsvimg(:,:,2) >= saturation_min) & (hsvimg(:,:,2) <= saturation_max) & ...
                       (hsvimg(:,:,3) >= value_min) & (hsvimg(:,:,3) <= value_max);

                % Use morphological operations to clean the image
                remove_object_image = bwareaopen(mask, 500); % Remove small objects

                clean_image = imclose(remove_object_image, strel('disk', 5)); % Remove noise and smooth the edges

                holes_image = imfill(clean_image, 'holes'); % Fill holes

                gethole_image = holes_image - clean_image;

                % Define a color range to isolate human skin
                hue_min_skin = 0.05; % Adjust as needed
                hue_max_skin = 0.2; % Adjust as needed
                saturation_min_skin = 0.3; % Adjust as needed
                saturation_max_skin = 0.5; % Adjust as needed
                value_min_skin = 0.4; % Adjust as needed
                value_max_skin = 1; % Adjust as needed
                
                % Apply a mask based on the skin color range
                skin_mask = (hsvimg(:,:,1) >= hue_min_skin) & (hsvimg(:,:,1) <= hue_max_skin) & ...
                            (hsvimg(:,:,2) >= saturation_min_skin) & (hsvimg(:,:,2) <= saturation_max_skin) & ...
                            (hsvimg(:,:,3) >= value_min_skin) & (hsvimg(:,:,3) <= value_max_skin);

                % Check if the gethole_image contains human skin
                gethole_skin = gethole_image & skin_mask;

                % If the gethole_skin image contains any non-zero pixels, it means the hole or tear exposes human skin
                if ~(all(gethole_skin(:)==0))
                    % Label connected components
                    [labeledImg, numComponents] = bwlabel(gethole_image);
                    %disp(numComponents);
                
                    % Analyze the connected components to find tearing or holes
                    stats = regionprops(labeledImg, 'Area', 'BoundingBox', 'Centroid');
                    %disp(stats(1));
                
                    % Set thresholds to differentiate tearing or holes from small artifacts
                    holeThreshold = 1000;
                    tearThreshold = 10000;
                
                    for i = 1:numComponents
                        bbox = stats(i).BoundingBox;
                        if stats(i).Area > holeThreshold && stats(i).Area < tearThreshold
                            % Draw a blue bounding box around the hole region
                            img = insertShape(img, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'blue');
                            % Label the region as a hole
                            img = insertText(img, bbox(1:2), 'Hole', 'FontSize', 50, 'BoxColor', 'blue', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Cotton, Defect: Hole';
                        elseif stats(i).Area >= tearThreshold
                            % Draw a red bounding box around the tearing region
                            img = insertShape(img, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'red');
                            % Label the region as tearing
                            img = insertText(img, bbox(1:2), 'Tear', 'FontSize', 50, 'BoxColor', 'red', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Cotton, Defect: Tear';
                        end
                    end
                
                    % Display the original image with the detected tearing or holes
                    figure;
                    imshow(img);
                    title('Detected Tearing or Holes Image');
                else
                    % Handle dirt or dots detection
                    % Use the original mask to detect the dirt or dots
                    dirt_dots_image = img;
                
                    % Label connected components
                    [labeledDirtDotsImg, numDirtDotsComponents] = bwlabel(gethole_image);
                
                    % Analyze the connected components to find dots
                    dirtDotsStats = regionprops(labeledDirtDotsImg, 'Area', 'BoundingBox');
                
                    % Set thresholds to differentiate tearing or holes from small artifacts
                    dotThreshold = 300;
                    dirtThreshold = 10000;
                
                    for i = 1:numDirtDotsComponents
                    bbox = dirtDotsStats(i).BoundingBox;
                
                        if dirtDotsStats(i).Area > dotThreshold && dirtDotsStats(i).Area < dirtThreshold
                            % Draw a blue bounding box around the hole region
                            dirt_dots_image = insertShape(dirt_dots_image, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'blue');
                            % Label the region as a hole
                            dirt_dots_image = insertText(dirt_dots_image, bbox(1:2), 'Dot', 'FontSize', 50, 'BoxColor', 'blue', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Cotton, Defect: Dots';
                        elseif dirtDotsStats(i).Area >= dirtThreshold
                            % Draw a red bounding box around the tearing region
                            dirt_dots_image = insertShape(dirt_dots_image, 'Rectangle', bbox, 'LineWidth', 10, 'Color', 'red');
                            % Label the region as tearing
                            dirt_dots_image = insertText(dirt_dots_image, bbox(1:2), 'Dirty', 'FontSize', 50, 'BoxColor', 'red', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            results = 'Glove type: Cotton, Defect: Dirty';
                        end
                    end
                
                
                    % Display the dirt or dots on the glove
                    figure;
                    imshow(dirt_dots_image);
                    title('Detected Dirt or Dots Image');
                end

            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.tif;*.tiff', 'Image Files (*.bmp, *.jpg, *.jpeg, *.png, *.tif, *.tiff)'});
            
            if isequal(file, 0) || isequal(path, 0)
                return; % If the user canceled the file selection, do nothing
            end

            % Read the selected image and display it in the Image UI component
            img = imread(fullfile(path, file));
            app.Image.ImageSource = img;

            % Perform glove defect detection and show the result in the TextArea
            app.result.Text = detectGloveDefect(app,img);
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.GloveDefectDetectionLabel = uilabel(app.UIFigure);
            app.GloveDefectDetectionLabel.Text = 'Glove Defect Detection';
            app.Image = uiimage(app.UIFigure);
            app.DetectedresultLabel = uilabel(app.UIFigure);
            app.DetectedresultLabel.Text = 'Detected result: ';
            app.result = uilabel(app.UIFigure);
            app.result.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group23

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end